classdef MI_Trails < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        StartButton              matlab.ui.control.Button
        LabelCenter              matlab.ui.control.Label
        EndButton                matlab.ui.control.Button
        LabelSound               matlab.ui.control.Label
        SomethingEditFieldLabel  matlab.ui.control.Label
        SomethingEditField       matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            n = app.SomethingEditField.Value;
            for i = 1:n
                rest_time = rand(1,1);
                class = randperm(4);
                
                    tic
                for j = 1:length(class)
                    app.LabelSound.Text = "Alert!";
                    [y,Fs] = audioread('beep.wav');
                    sound(y,Fs)
                    app.LabelSound.Text = "Alert!";
                    app.LabelCenter.Text = "X";
                    pause(2)
                    app.LabelSound.Text = "";                   
                  
                    switch class(j)
                        case 1 %left hand
                            app.LabelCenter.Text = "Left Hand";
                            pause(4)
                        case 2 %right hand
                            app.LabelCenter.Text = "Right Hand";
                            pause(4)
                        case 3 %tounge
                            app.LabelCenter.Text = "Tongue";
                            pause(4)
                        case 4 %both feets
                            app.LabelCenter.Text = "Feets";
                            pause(4)
                    end
                            app.LabelCenter.Text = "Rest";
                            pause(1.5 + rest_time);
                        disp(toc);
                end
            end
                
           app.LabelCenter.Text = "Over";       
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.LabelCenter = uilabel(app.UIFigure);
            app.LabelCenter.Text = '';
            app.EndButton = uibutton(app.UIFigure, 'push');
            app.EndButton.Text = 'End';
            app.LabelSound = uilabel(app.UIFigure);
            app.LabelSound.Text = '';
            app.SomethingEditFieldLabel = uilabel(app.UIFigure);
            app.SomethingEditFieldLabel.Text = '#Something';
            app.SomethingEditField = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = MI_Trails

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end